classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        SettingsTab           matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        StatusPanel           matlab.ui.container.Panel
        GridLayout5_3         matlab.ui.container.GridLayout
        InitializeButton      matlab.ui.control.Button
        InitializedLamp       matlab.ui.control.Lamp
        InitializedLampLabel  matlab.ui.control.Label
        CameraPanel           matlab.ui.container.Panel
        GridLayout5_2         matlab.ui.container.GridLayout
        UseCaseDropDown       matlab.ui.control.DropDown
        UseCaseDropDownLabel  matlab.ui.control.Label
        SDKPanel              matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        SDKInstallationLocationEditField  matlab.ui.control.EditField
        SDKInstallationLocationEditFieldLabel  matlab.ui.control.Label
        StreamTab             matlab.ui.container.Tab
        GridLayout2_2         matlab.ui.container.GridLayout
        ControlsPanel         matlab.ui.container.Panel
        GridLayout6           matlab.ui.container.GridLayout
        StartButton_3         matlab.ui.control.Button
        StopButton_2          matlab.ui.control.Button
        StartButton_2         matlab.ui.control.Button
        GridLayout4           matlab.ui.container.GridLayout
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        MappingTab            matlab.ui.container.Tab
        GridLayout2_3         matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        cameraDevice   % Flexx2 Camera Variable
        StartButton    %   matlab.ui.control.Button
        StopButton     %   matlab.ui.control.Button
        isRunning = false;      % Description
        backgroundTask  % Description
        timerObj
    end
    
    methods (Access = private)
    % =====================================================================
    % FUNCTION SECTION
    % =====================================================================
        % Initialize the camera
        function initializeCamera(app)
            % Set Status Button Color to Yellow during initialization
            app.InitializedLamp.Color = "Yellow";
            drawnow;

            % Add path to the Royale MATLAB SDK (change the path based on your installation)
            addpath(app.SDKInstallationLocationEditField.Value);

            % Initialize the Camera and Check Connection
            manager = royale.CameraManager();
            camlist = manager.getConnectedCameraList();
            if isempty(camlist)
                error('No camera found. Please check the connection.');
            end
            
            % Create camera device object
            app.cameraDevice = manager.createCamera(camlist{1});
            app.cameraDevice.initialize();
            
            % Setting a use case (adjust the index depending on your setup)
            selectedIndex = app.UseCaseDropDown.Value;
            app.cameraDevice.setUseCase(selectedIndex);
            
            % Set Status Button Color to Green after successful initialization
            app.InitializedLamp.Color = "Green";
        end

        % Start the visualization process
        function startVisualization(app)
            % Set the flag to true to start the loop
            app.isRunning = true;
            
            % Create and configure the timer object
            app.timerObj = timer('ExecutionMode', 'fixedRate', ...
                                 'Period', 1/60, ...  % Run at 60 FPS
                                 'TimerFcn', @(~,~) app.runVisualization);  % Call runVisualization on every tick
            
            % Start the timer
            start(app.timerObj);
        end
        
    % Visualization function that runs in the background
    function runVisualization(app)
        % Start capturing frames
        app.cameraDevice.startCapture();

        % Loop to capture frames and plot the data in real-time
        while app.isRunning
            % Capture the depth data
            DepthData = app.cameraDevice.getData();
            
            % Extract the Z data (depth map)
            Z = double(DepthData.z);  % Depth in meters
            
            % Check if Z data is valid
            if isempty(Z) || all(Z(:) == 0)
                disp('Warning: No valid depth data captured.');
                pause(1/60);  % Adjust for 60 FPS and wait for the next frame
                continue;  % Skip to the next iteration
            end
            
            % Ensure the size of Z matches the expected plotting dimensions
            % Adjust XLim, YLim, and ColorLimits if necessary
            imagesc(app.UIAxes, Z);  % Visualize the depth map in the app's UIAxes
            colormap(app.UIAxes, 'jet');  % Apply a color map to enhance visualization
            colorbar(app.UIAxes);  % Add a color bar for the depth map
            
            % Set limits for the colorbar to improve contrast (optional)
            caxis(app.UIAxes, [min(Z(:)), max(Z(:))]);
            
            % Set axis labels and title
            title(app.UIAxes, 'Depth Map - Streaming');
            xlabel(app.UIAxes, 'X Pixel');
            ylabel(app.UIAxes, 'Y Pixel');
            axis(app.UIAxes, 'equal');
            
            % Refresh the plot
            drawnow;
            
            % Pause for a short time to simulate real-time streaming
            pause(1/60);  % Adjust for 60 FPS
        end

        % Stop capturing frames
        app.cameraDevice.stopCapture();
    end
        
        % Stop the visualization process
        function stopVisualization(app)
            % Set the flag to false to stop the loop
            app.isRunning = false;
            
            % Cancel the background task (optional, if you need to force-stop it)
            if ~isempty(app.backgroundTask) && ~app.backgroundTask.Cancelled
                cancel(app.backgroundTask);
            end
            
            % Start the timer
            stop(app.timerObj);
        end
    % =====================================================================
    % FUNCTION SECTION (END)
    % =====================================================================
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            initializeCamera(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.cameraDevice)
                app.cameraDevice.stopCapture();
                delete(app.cameraDevice);
            end
            delete(app);
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            startVisualization(app)
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stopVisualization(app)
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
           app.isRunning = true;
            runVisualization(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.SDKPanel = uipanel(app.GridLayout2);
            app.SDKPanel.Title = 'SDK';
            app.GridLayout5 = uigridlayout(app.SDKPanel);
            app.SDKInstallationLocationEditFieldLabel = uilabel(app.GridLayout5);
            app.SDKInstallationLocationEditFieldLabel.Text = 'SDK Installation Location';
            app.SDKInstallationLocationEditField = uieditfield(app.GridLayout5, 'text');
            app.SDKInstallationLocationEditField.Value = 'C:\Program Files\royale\4.22.0.926\matlab';
            app.CameraPanel = uipanel(app.GridLayout2);
            app.CameraPanel.Title = 'Camera';
            app.GridLayout5_2 = uigridlayout(app.CameraPanel);
            app.UseCaseDropDownLabel = uilabel(app.GridLayout5_2);
            app.UseCaseDropDownLabel.Text = 'Use Case';
            app.UseCaseDropDown = uidropdown(app.GridLayout5_2);
            app.UseCaseDropDown.Items = {'Mode_5_15fps', 'Mode_5_30fps', 'Mode_5_45fps', 'Mode_5_60fps', 'Mode_9_10fps', 'Mode_9_15fps', 'Mode_9_20fps', 'Mode_9_30fps', 'Mode_9_5fps'};
            app.UseCaseDropDown.Value = 'Mode_5_15fps';
            app.StatusPanel = uipanel(app.GridLayout2);
            app.StatusPanel.Title = 'Status';
            app.GridLayout5_3 = uigridlayout(app.StatusPanel);
            app.InitializedLampLabel = uilabel(app.GridLayout5_3);
            app.InitializedLampLabel.Text = 'Initialized?';
            app.InitializedLamp = uilamp(app.GridLayout5_3);
            app.InitializeButton = uibutton(app.GridLayout5_3, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StreamTab = uitab(app.TabGroup);
            app.StreamTab.Title = 'Stream';
            app.GridLayout2_2 = uigridlayout(app.StreamTab);
            app.GridLayout4 = uigridlayout(app.GridLayout2_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ControlsPanel = uipanel(app.GridLayout2_2);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout6 = uigridlayout(app.ControlsPanel);
            app.StartButton_2 = uibutton(app.GridLayout6, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.GridLayout6, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.StartButton_3 = uibutton(app.GridLayout6, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.MappingTab = uitab(app.TabGroup);
            app.MappingTab.Title = 'Mapping';
            app.GridLayout2_3 = uigridlayout(app.MappingTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end